classdef SPRSoftware < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Toolbar                   matlab.ui.container.Toolbar
        ResetTool                 matlab.ui.container.toolbar.PushTool
        ExportTool                matlab.ui.container.toolbar.PushTool
        CopyGraph                 matlab.ui.container.toolbar.ToggleTool
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        StatusLamp                matlab.ui.control.Lamp
        Panel                     matlab.ui.container.Panel
        SimulationPanel           matlab.ui.container.Panel
        Fornkandd1LowerLimit2UpperLimitLabel  matlab.ui.control.Label
        FinerLabel_3              matlab.ui.control.Label
        CoarserLabel_2            matlab.ui.control.Label
        FinerLabel                matlab.ui.control.Label
        CoarserLabel              matlab.ui.control.Label
        FittingModeLabel          matlab.ui.control.Label
        FittingModeButtonGroup    matlab.ui.container.ButtonGroup
        AutoButton                matlab.ui.control.RadioButton
        ManualButton              matlab.ui.control.RadioButton
        OffButton                 matlab.ui.control.RadioButton
        ResetRangesButton         matlab.ui.control.Button
        AutofitValueLabel         matlab.ui.control.Label
        AutofitRunButton          matlab.ui.control.Button
        dSlider                   matlab.ui.control.Slider
        nmSliderLabel             matlab.ui.control.Label
        dRightButton              matlab.ui.control.Button
        dLeftButton               matlab.ui.control.Button
        kSlider                   matlab.ui.control.Slider
        kLabel                    matlab.ui.control.Label
        kRightButton              matlab.ui.control.Button
        kLeftButton               matlab.ui.control.Button
        nSlider                   matlab.ui.control.Slider
        SliderLabel               matlab.ui.control.Label
        LayerSelectionDropDown    matlab.ui.control.DropDown
        ButtonTuningSlider        matlab.ui.control.Slider
        ButtonTuningLabel         matlab.ui.control.Label
        AutofitSlider             matlab.ui.control.Slider
        AutofitLabel              matlab.ui.control.Label
        nRightButton              matlab.ui.control.Button
        nLeftButton               matlab.ui.control.Button
        LayerParamUITable         matlab.ui.control.Table
        StatusTextArea            matlab.ui.control.TextArea
        SetupPanel                matlab.ui.container.Panel
        PrismAngleEditField       matlab.ui.control.NumericEditField
        PrismAngleEditFieldLabel  matlab.ui.control.Label
        ResetApp                  matlab.ui.control.Button
        PrismTypeDropDown         matlab.ui.control.DropDown
        PrismtypeDropDownLabel    matlab.ui.control.Label
        LambdaEditField           matlab.ui.control.NumericEditField
        nmLabel                   matlab.ui.control.Label
        LoadDataTextArea          matlab.ui.control.TextArea
        LoadDataButton            matlab.ui.control.Button
        NumOfLayersSpinner        matlab.ui.control.Spinner
        NooflayersSpinnerLabel    matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        RightPanelGridLayout      matlab.ui.container.GridLayout
        TabGroup                  matlab.ui.container.TabGroup
        MainTab                   matlab.ui.container.Tab
        MainTabGridLayout         matlab.ui.container.GridLayout
        InfoPanel                 matlab.ui.container.Panel
        nkdvalueTextArea          matlab.ui.control.TextArea
        nkdvalueTextAreaLabel     matlab.ui.control.Label
        InfoTextArea              matlab.ui.control.EditField
        AppNameLabel              matlab.ui.control.Label
        MainUIAxes                matlab.ui.control.UIAxes
        DataTab                   matlab.ui.container.Tab
        ResultsTable              matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = public)
        LayerList
        Lambda = 632.8e-9 
        PrismDegree = 60 % (!) Still working on this
        AutofitTuning = 50
        DataPath
        ParamRanges = {[0.001 3], [0 5], [0 100]}
        Layers = cell(10, 9)
        UsedLayers
        SelectedLayer
        ButtonTuningSize
        ParamTuningSize
        FittingMode
        ExperimentalData
        DefaultStatus = 'App is ready.'
        ScatterDataHndl
        LineSimulationHndl
        IsDataLoaded = false
        LayersTabExtended;
        ThetaRange
        SelectedCell
        ParamLetters = ["n", "k", "d"]
        ColumnIdx = [2, 5, 8]
        AppVersion = '1.0'
        AppShortName = 'PRISMA'
        AppFullName = 'Plasma Resonance Integrated Software with Model Auto-Fitting'
        SimHndl
        SimulatedR              double
        SimulatedTheta          double
        RawThetaRange           double
    end


    methods (Access = private)

        function LayerList = listLayers(app)
            LayerList = "Layer " + string(1:app.NumOfLayersSpinner.Value);
        end

       
        function updateParamTable(app)
            app.UsedLayers = app.Layers(1:app.NumOfLayersSpinner.Value, :);
            app.LayerParamUITable.Data = app.UsedLayers;
        end

        function setInitialSlider(app, SetRange, SetValue)
            if SetRange
                [app.nSlider.Limits, app.kSlider.Limits, app.dSlider.Limits] = deal(app.ParamRanges{:});
            end
            if SetValue
                [app.Layers{:, 2}, app.nSlider.Value]  = deal(min(app.nSlider.Limits));
                [app.Layers{:, 5}, app.kSlider.Value]  = deal(min(app.kSlider.Limits));
                [app.Layers{:, 8}, app.dSlider.Value]  = deal(min(app.dSlider.Limits));
            end

        end

        function extendSliderRange(app, SliderValue, SliderHandle, Increment)
            if SliderValue >= SliderHandle.Limits(2)
                SliderHandle.Limits(2) = SliderHandle.Limits(2) + Increment;
                set(app.ResetRangesButton, Visible = 'on', Enable = 'on');
            end
        end

        function updateStatus(app, StatusText)
            app.StatusTextArea.Value = StatusText;

            % Stop and delete any existing status timer
            OldTimers = timerfind('Name', 'StatusTimer');
            if ~isempty(OldTimers)
                stop(OldTimers);
                delete(OldTimers);
            end

            % Create new timer
            StatusTimer = timer(...
                Name = 'StatusTimer', ...
                StartDelay = 8, ...
                TimerFcn = @(t, ~) statusTimerCallback(t, app));
            start(StatusTimer);
        end

        function ScatterDataHndl = plotExperimentalData(app)
            ScatterDataHndl = scatter(app.MainUIAxes, app.ExperimentalData(:, 1), ...
                app.ExperimentalData(:, 2), 'r.');
        end

        function setupMainAxes(app)
            set(app.MainUIAxes, Box = 'on');
        end

        function setupSimulation(app)
            if ~app.IsDataLoaded
                app.ExperimentalData(:, 1) = 30 : 0.1 : 70;
                app.ExperimentalData(:, 2) = NaN;
            end
        end

        function updateSliderValue(app)
            app.nSlider.Value = app.Layers{app.SelectedLayer, 2};
            app.kSlider.Value = app.Layers{app.SelectedLayer, 5};
            app.dSlider.Value = app.Layers{app.SelectedLayer, 8};
        end

        function sliderChangesTableSelection(app, ParamIdx)
            app.LayerParamUITable.Selection = [app.SelectedLayer, ParamIdx];
        end

        function simulateData(app, Mode)
            app.StatusLamp.Color = [1,0,0];
            app.StatusTextArea.Value = "Simulation in progress...";
            if strcmp(Mode, 'Auto')
                RelatedColumns = reshape(1:9, 3, 3)';
                for l = 1 : height(app.UsedLayers)
                    for c = 1 : height(RelatedColumns)
                        ReshapedLayers{l, c} = cell2mat(app.UsedLayers(l, RelatedColumns(c, :))); %#ok<*AGROW>
                        if  numel(ReshapedLayers{l, c}) == 3
                            ReshapedLayers{l, c}(2) = [];
                        end
                    end
                end
                LayersWithRanges = cellfun(@(X) numel(X) == 2, ReshapedLayers);
                AutofitInterval = num2cell(  cellfun( @(X) round(mean(X), 1, 'significant') ...
                    / app.AutofitSlider.Value, ReshapedLayers(LayersWithRanges) )  );
                AutofitVectors = ReshapedLayers;
                AutofitVectors(LayersWithRanges) = cellfun( @(X, Y) X(1) : Y : X(end), ...
                    AutofitVectors(LayersWithRanges), AutofitInterval, UniformOutput = false );
                SimulationLayers = combvec(AutofitVectors{:});
                SimulationLayers = reshape(SimulationLayers, [], 3, size(SimulationLayers, 2));
                SimulationLayers(:, 3, :) = 1e-9 * SimulationLayers(:, 3, :);
                SimulationLayers(:, 4, :) = arrayfun(@(n, k) complex(n.^2 - k.^2, 2 .* n .* k), ...
                    SimulationLayers(:, 1, :), SimulationLayers(:, 2, :)); % Dielectric constant         
                NumOfIterations = size(SimulationLayers, 3);
            elseif strcmp(Mode, 'Manual')
                for i = 1 : height(app.UsedLayers)
                    for j = 1 : width(app.UsedLayers)
                        if isempty(app.UsedLayers{i, j})
                            SimulationLayers(i, j) = NaN;
                        else
                            SimulationLayers(i, j) = app.UsedLayers{i, j};
                        end
                    end
                end
                SimulationLayers = SimulationLayers(:, [2, 5, 8]);
                SimulationLayers(:, 3, :) = 1e-9 * SimulationLayers(:, 3, :);
                SimulationLayers(:, 4, :) = arrayfun(@(n, k) complex(n.^2 - k.^2, 2 .* n .* k), ...
                    SimulationLayers(:, 1, :), SimulationLayers(:, 2, :)); % Dielectric constant
                NumOfIterations = 1;
            end

            qCalculate = @(n1, e, th) sqrt( e - n1.^2 * sin(th).^2 ) ./ e;
            betaCalculate = @(n1, d, e, th) (2*d*pi / app.Lambda) .* sqrt(e - n1.^2*sin(th).^2);

            ThetaRangeRad = deg2rad(app.ThetaRange);
            [RMSE, Corr2] = deal(NaN(NumOfIterations, 1));
            [R, T] = deal(NaN(length(ThetaRangeRad), NumOfIterations));
            BestSet = 1;

            if strcmp(Mode, 'Auto')
                ProgressDlg = uiprogressdlg(app.UIFigure, ...
                    Title = 'Auto-fitting', ...
                    Message = 'Starting...', ...
                    Cancelable = 'on', ...
                    CancelText = 'Stop');
            end

            for s = 1 : NumOfIterations
                % Calculations
                Theta = deg2rad(app.PrismDegree) + asin( 1/SimulationLayers(1, 1, s) * sin(ThetaRangeRad - deg2rad(app.PrismDegree)) ); % (!) WIP
                for t = 1 : length(Theta)
                    q = qCalculate(SimulationLayers(1, 1, s), SimulationLayers(:, 4, s), Theta(t));
                    Beta = betaCalculate(SimulationLayers(1, 1, s), SimulationLayers(:, 3, s), SimulationLayers(:, 4, s), Theta(t));
                    for l = 2 : height(SimulationLayers) - 1
                        m(l, 1, 1) = cos(Beta(l));
                        m(l, 1, 2) = -1i*sin(Beta(l)) / q(l);
                        m(l, 2, 1) = -1i*sin(Beta(l)) * q(l);
                        m(l, 2, 2) = cos(Beta(l));
                    end
                    M = [1 0; 0 1];
                    for l = 2 : height(SimulationLayers) - 1
                        M1(:, :) = m(l, :, :);
                        M = M * M1;
                    end
                    rp = ( (M(1, 1)+M(1, 2)*q(end))*q(1) - (M(2, 1)+M(2, 2)*q(end)) ) / ...
                        ( (M(1, 1)+M(1, 2)*q(end))*q(1) + (M(2, 1)+M(2, 2)*q(end)) );
                    tp = 2*q(1) / ( (M(1, 1)+M(1, 2)*q(end)) * q(1)+(M(2, 1)+M(2, 2)*q(end)) );
                    R(t, s) = rp * conj(rp); % Reflectance
                    T(t, s) = tp*conj(tp) / cos(Theta(t))*SimulationLayers(1, 1, s)*q(end); % Transmission
              
                end


                % Correlation values
                ThetaVecDeg = app.ThetaRange;
                Corr = corrcoef(app.ExperimentalData(:, 2), R(:, s));
                Corr2(s) = Corr(1, 2)^2;
                RMSE(s) = sqrt( sse(app.ExperimentalData(:, 2), R(:, s)) / (height(app.ExperimentalData) - 1) );

                % Plotting
                if (strcmp(Mode, 'Auto') && RMSE(s) == min(RMSE)) || strcmp(Mode, 'Manual')
                    SmoothThetaVec = linspace(ThetaVecDeg(1), ThetaVecDeg(end), 500);
                    SmoothR = spline(ThetaVecDeg, R(:, s), SmoothThetaVec);
                    try %#ok<TRYNC>
                        delete(app.SimHndl);
                        delete(ParamHndl);
                    end
                    hold(app.MainUIAxes, 'on');
                    app.SimHndl = plot(app.MainUIAxes, SmoothThetaVec, SmoothR, 'b', DisplayName = 'Prediction');
                    % ParamHndl = annotation('textbox', [0.14,0.12,0.27,0.12], String = composeParams(s, AutofitLayers, RMSE, Corr2),...
                    %     Interpreter = 'latex');
                    drawnow;
                    BestSet = s;

                    % Draw resonance angle vertical line
                    [~, MinIdx] = min(SmoothR);
                    ResonanceAngle = SmoothThetaVec(MinIdx);
                    delete(findobj(app.MainUIAxes, 'Tag', 'ResonanceLine'));
                    xline(app.MainUIAxes, ResonanceAngle, '--r', ...
                        sprintf('\\theta_{res} = %.2f°', ResonanceAngle), ...
                        LineWidth = 1.5, ...
                        LabelVerticalAlignment = 'bottom', ...
                        LabelHorizontalAlignment = 'right', ...
                        Tag = 'ResonanceLine');

                    % FWHM calculation
                    HalfMax = (min(SmoothR) + max(SmoothR)) / 2;
                    AboveHalf = SmoothR >= HalfMax;
                    Transitions = diff(AboveHalf);
                    FallingEdge = find(Transitions == -1, 1, 'first');
                    RisingEdge  = find(Transitions == 1,  1, 'last');
                    if ~isempty(FallingEdge) && ~isempty(RisingEdge)
                        FWHM = SmoothThetaVec(RisingEdge) - SmoothThetaVec(FallingEdge);
                        % Draw FWHM bracket on plot
                        delete(findobj(app.MainUIAxes, 'Tag', 'FWHMLine'));
                        yline(app.MainUIAxes, HalfMax, '--k', ...
                            sprintf('FWHM = %.2f°', FWHM), ...
                            LineWidth = 1, ...
                            LabelVerticalAlignment = 'bottom', ...
                            LabelHorizontalAlignment = 'left', ...
                            Tag = 'FWHMLine');
                    else
                        FWHM = NaN;
                    end

                    layerLines = {};
                    for li = 1 : height(SimulationLayers)
                        layerLines{end+1} = sprintf('Layer %d:  n = %.4f  |  k = %.4f  |  d = %.4f nm', ...
                            li, SimulationLayers(li,1,BestSet), SimulationLayers(li,2,BestSet), ...
                            SimulationLayers(li,3,BestSet) / 1e-9);
                    end
                    
                    app.InfoTextArea.Value = sprintf('Runs = %d  |  RMSE = %.6f  |  R² = %.6f  |  θres = %.2f°  |  FWHM = %.2f°', ...
                        BestSet, RMSE(BestSet), Corr2(BestSet), ResonanceAngle, FWHM);

                    nkdLines = {};
                    for li = 1 : height(SimulationLayers)
                        nkdLines{end+1} = sprintf('Layer %d:  n = %.4f  |  k = %.4f  |  d = %.4f nm', ...
                            li, SimulationLayers(li,1,BestSet), SimulationLayers(li,2,BestSet), ...
                            SimulationLayers(li,3,BestSet)/1e-9);
                    end
                    app.nkdvalueTextArea.Value = nkdLines;
                end
                  
                % SmoothThetaVec = ThetaVecDeg(1) : 0.1 : ThetaVecDeg(end);
                % SmoothR = spline(ThetaVecDeg, R(:, s), SmoothThetaVec);
                % SimHndl = plot(SmoothThetaVec, SmoothR, 'b', DisplayName = 'Prediction');

               
                % Progress
                if strcmp(Mode, 'Auto')
                    ProgressPercent = s / NumOfIterations;
                    ProgressDlg.Value = ProgressPercent;
                    ProgressDlg.Message = sprintf('Iteration %d of %d  (%.1f%%)', s, NumOfIterations, ProgressPercent*100);
                    if ProgressDlg.CancelRequested
                        break;
                    end
                end
            end

             if strcmp(Mode, 'Auto') && isvalid(ProgressDlg)
                close(ProgressDlg);
             end


             app.StatusLamp.Color = [0,1,0];
             app.StatusTextArea.Value = "Simulation completes.";
             

             for li = 1 : height(SimulationLayers)
                 app.LayerParamUITable.Data(li, 2) = num2cell(SimulationLayers(li, 1, BestSet));
                 app.LayerParamUITable.Data(li, 5) = num2cell(SimulationLayers(li, 2, BestSet));
                 app.LayerParamUITable.Data(li, 8) = num2cell(SimulationLayers(li, 3, BestSet) / 1e-9);

                 % Sync best fit values back to app.Layers
                 app.Layers{li, 2} = SimulationLayers(li, 1, BestSet);
                 app.Layers{li, 5} = SimulationLayers(li, 2, BestSet);
                 app.Layers{li, 8} = SimulationLayers(li, 3, BestSet) / 1e-9;
             end
             app.UsedLayers = app.Layers(1 : app.NumOfLayersSpinner.Value, :);

             % Save simulated results for Results table
             app.SimulatedTheta = app.ThetaRange;
             app.SimulatedR = real(R(:, BestSet));
             updateResultsTable(app);
        end
        function statusTimerCallback(~, app)
            app.StatusTextArea.Value = app.DefaultStatus;
        end
        function updateResultsTable(app)
           
            if isempty(app.ExperimentalData) || isempty(app.SimulatedR)
               
                return;
            end

            NumPoints = length(app.ExperimentalData(:,1));
            TableData = cell(NumPoints, 3);
            for i = 1 : NumPoints
                TableData{i, 1} = app.ExperimentalData(i, 1);
                TableData{i, 2} = app.ExperimentalData(i, 2);
                TableData{i, 3} = app.SimulatedR(i);
            end
            app.ResultsTable.Data = TableData;
        end  % <-- end of updateResultsTable
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StatusTextArea.Value = app.DefaultStatus;
            app.UIFigure.Name = sprintf('%s v%s', app.AppShortName, app.AppVersion);
            app.AppNameLabel.Text = sprintf('%s (%s) v%s', app.AppFullName, app.AppShortName, app.AppVersion);
            app.LayerSelectionDropDown.Items = listLayers(app);
            app.LambdaEditField.Value = app.Lambda / 1e-9;
            app.PrismAngleEditField.Value = app.PrismDegree;
            app.SelectedLayer = app.NumOfLayersSpinner.Value;
            app.AutoButton.Enable = 'off';
            FittingModeButtonGroupSelectionChanged(app);

            TableColumnNames = {'𝑛₁', '𝑛', '𝑛₂', 'k₁', 'k', 'k₂', '𝑑₁', '𝑑', '𝑑₂'};
            set(app.LayerParamUITable, ColumnName = TableColumnNames, RowName = 'numbered', ...
                ColumnEditable = false, ColumnWidth = repelem({45}, 1, 9)) ;
           
            setInitialSlider(app, true, true);
            updateParamTable(app);
            set(app.ResetRangesButton, Visible = 'off', Enable = 'off');
            ButtonTuningSliderValueChanged(app);
            LayerSelectionDropDownValueChanged(app);
                setupMainAxes(app);
    set(app.ResultsTable, ...
        ColumnName = {'Angle (deg)', 'Reflectance (Exp)', 'Reflectance (Sim)'}, ...
        ColumnWidth = {120, 150, 150}, ...
        ColumnEditable = false, ...
        RowName = 'numbered');
        end

        % Value changed function: ButtonTuningSlider
        function ButtonTuningSliderValueChanged(app, event)
            app.ButtonTuningSlider.Value = round(app.ButtonTuningSlider.Value, 0);
            app.ButtonTuningSize = app.ButtonTuningSlider.Value;
            app.ParamTuningSize = [mean(app.nSlider.Limits), mean(app.kSlider.Limits), mean(app.dSlider.Limits)] ... 
                / 10^app.ButtonTuningSize;
        end

        % Value changing function: AutofitSlider
        function AutofitSliderValueChanging(app, event)
            app.AutofitSlider.Value = round(event.Value, 0);
            app.AutofitValueLabel.Text = num2str(app.AutofitSlider.Value);
        end

        % Value changed function: NumOfLayersSpinner
        function NumOfLayersSpinnerValueChanged(app, event)
            app.UsedLayers = app.Layers(1:event.Value, :);
            app.LayerSelectionDropDown.Items = listLayers(app);
            updateParamTable(app);
        end

        % Value changing function: nSlider
        function nSliderValueChanging(app, event)
            app.Layers{app.SelectedLayer, 2} = event.Value;
            updateParamTable(app);
            sliderChangesTableSelection(app, 2);
            if app.ManualButton.Value && ~isempty(app.ThetaRange)
                simulateData(app,'Manual');
            end
        end

        % Value changed function: LayerSelectionDropDown
        function LayerSelectionDropDownValueChanged(app, event)
            app.SelectedLayer = str2double(erase(app.LayerSelectionDropDown.Value, "Layer "));
            updateSliderValue(app);
        end

        % Value changing function: kSlider
        function kSliderValueChanging(app, event)
            app.Layers{app.SelectedLayer, 5} = event.Value;
            updateParamTable(app);
            sliderChangesTableSelection(app, 5);
            if app.ManualButton.Value && ~isempty(app.ThetaRange)
                simulateData(app,'Manual');
            end
        end

        % Value changing function: dSlider
        function dSliderValueChanging(app, event)
            app.Layers{app.SelectedLayer, 8} = event.Value;
            updateParamTable(app);
            sliderChangesTableSelection(app, 8);
            if app.ManualButton.Value && ~isempty(app.ThetaRange)
                simulateData(app,'Manual');
            end
        end

        % Value changed function: nSlider
        function nSliderValueChanged(app, event)
            extendSliderRange(app, event.Value, app.nSlider, 0.5)
        end

        % Value changed function: kSlider
        function kSliderValueChanged(app, event)
            extendSliderRange(app, event.Value, app.kSlider, 0.5)
        end

        % Value changed function: dSlider
        function dSliderValueChanged(app, event)
            extendSliderRange(app, event.Value, app.dSlider, 5)
        end

        % Button pushed function: ResetRangesButton
        function ResetRangesButtonPushed(app, event)
            setInitialSlider(app, true, false);
            set(app.ResetRangesButton, Visible = 'off', Enable = 'off');
        end

        % Button pushed function: dLeftButton, dRightButton, kLeftButton, 
        % ...and 3 other components
        function TuningButtonPushed(app, event)
            PushedButton = event.Source.Tag;
            ColIdx = app.ColumnIdx(PushedButton(1) == app.ParamLetters);  
            ExtendValue = [0.5, 0.5, 5];
            Sign = PushedButton(2);
            sliderChangesTableSelection(app, ColIdx);

            if Sign == 'r'
                Change = + app.ParamTuningSize(PushedButton(1) == app.ParamLetters);
            else
                Change = - app.ParamTuningSize(PushedButton(1) == app.ParamLetters);
            end

            SliderName = PushedButton(1) + "Slider";
            NewValue = app.(SliderName).Value + Change;
            if NewValue > app.(SliderName).Limits(1) && NewValue < app.(SliderName).Limits(2)
                app.(SliderName).Value = NewValue;
                app.Layers{app.SelectedLayer, ColIdx} = app.(SliderName).Value;
            else
                extendSliderRange(app, NewValue, app.(SliderName), ExtendValue(ColIdx));
            end
            updateParamTable(app);
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            switch app.LoadDataButton.Text
                case 'Load data'
                    [DataName, app.DataPath] = uigetfile({'*.txt;*.csv;*.xlsx;*.xls', 'Supported files (*.txt, *.csv, *.xlsx, *.xls)'; '*.*', 'All files (*.*)'}, 'Load experimental data');
                    figure(app.UIFigure);
                    DataFullPath = fullfile(app.DataPath, DataName);
                    try
                        if endsWith(DataFullPath, '.xls', 'IgnoreCase', true)
                            app.ExperimentalData = readmatrix(DataFullPath, 'FileType', 'spreadsheet');
                        else
                            app.ExperimentalData = readmatrix(DataFullPath);
                        end

                        app.ExperimentalData = rmmissing(app.ExperimentalData);

                        % Save raw angles before conversion
                        app.RawThetaRange = app.ExperimentalData(:, 1);

                         % Convert rotation stage angles to internal angles at metal interface
                        % Formula: theta_internal = prism_angle - arcsin(n_prism * sin(prism_angle - theta_incident))
                        n_prism = app.Layers{1, 2};
                           if isempty(n_prism) || ~isnumeric(n_prism) || n_prism < 1.0
                            n_prism = 1.77861; % Default SF11
                        end
                        PrismRad = deg2rad(app.PrismDegree);
                        ThetaIncRad = deg2rad(app.ExperimentalData(:, 1));
                        ConvertedAngles = rad2deg( PrismRad - asin( n_prism .* sin(PrismRad - ThetaIncRad) ) );
                        app.ExperimentalData(:, 1) = ConvertedAngles;
                        disp(['Angle conversion: ' num2str(ThetaIncRad(1)*180/pi) '° -> ' num2str(ConvertedAngles(1)) '°']);
                        disp(['n_prism used: ' num2str(n_prism)]);

                        app.ThetaRange = app.ExperimentalData(:, 1);

                        app.LoadDataTextArea.Value = DataFullPath;
                        app.LoadDataButton.Text = 'Clear data';
                        app.ScatterDataHndl = plotExperimentalData(app);
                        updateStatus(app, "Data has been loaded and plotted.");
                        app.IsDataLoaded = true;
                        app.AutoButton.Enable = 'on';
                    catch
                        updateStatus(app, "(!) Data could not be read. Only *.txt, *.csv, *.xlsx, *.xls are supported.")
                    end
                case 'Clear data'
                    app.LoadDataTextArea.Value = '';
                    app.ExperimentalData = [];
                    app.LoadDataButton.Text = 'Load data';
                    delete(app.ScatterDataHndl);
                    app.IsDataLoaded = false;
                    app.AutoButton.Value = false;
                    app.AutoButton.Enable = 'off';
            end

        end

        % Selection changed function: FittingModeButtonGroup
        function FittingModeButtonGroupSelectionChanged(app, event)
            app.FittingMode = app.FittingModeButtonGroup.SelectedObject.Text;

            ManualFitGroup =  [app.nSlider, app.kSlider, app.dSlider, ...
                app.SliderLabel, app.kLabel, app.nmSliderLabel, ...
                app.nRightButton, app.nLeftButton, ...
                app.kLeftButton, app.kRightButton, app.dLeftButton, ...
                app.dRightButton, app.LayerSelectionDropDown, app.ButtonTuningLabel, ...
                app.ButtonTuningSlider];
            AutoFitGroup = [app.AutofitLabel, app.AutofitRunButton, app.AutofitSlider];
            set([ManualFitGroup, AutoFitGroup], Enable = 'off');
            set(ManualFitGroup, Enable = 'on');
            if strcmp(app.FittingMode, 'Auto')
                set(AutoFitGroup, Enable = 'on');
            end

            switch app.FittingMode
                case 'Manual'
                    InactiveColumnColor = uistyle(BackgroundColor = [0.9, 0.9, 0.9]);
                    addStyle(app.LayerParamUITable, InactiveColumnColor, 'column', ...
                        setdiff(1:9, app.ColumnIdx));
                case 'Auto'
                    app.LayerParamUITable.ColumnEditable( setdiff(1:9, app.ColumnIdx)) = true;
                    removeStyle(app.LayerParamUITable);
            end

        end

        % Cell edit callback: LayerParamUITable
        function LayerParamUITableCellEdit(app, event)
            % Validate input value
            if isnumeric(event.NewData)
                NewData = event.NewData;
            else
                NewData = str2double(event.NewData);
            end

            if isnan(NewData)
                app.LayerParamUITable.Data{event.Indices(1), event.Indices(2)} = [];
                updateStatus(app, 'Please enter numeric value only.');
                return;
            end
            app.Layers{event.Indices(1), event.Indices(2)} = NewData;
            app.UsedLayers = app.Layers(1:app.NumOfLayersSpinner.Value, :);
        end

        % Button pushed function: AutofitRunButton
        function AutofitRunButtonPushed(app, event)
            % Check error cells
            ErrorCellColor = uistyle('BackgroundColor', [1, 0.8, 0.8]);

            ErrorCell = false(size(app.UsedLayers));
            ErrorCellTimer = timer(StartDelay = 1, ...
                TimerFcn = @(~, ~) removeStyle(app.LayerParamUITable));

            IsNoLimitsEntered = all(cellfun(@isempty, app.UsedLayers(:, setdiff(1:9, app.ColumnIdx))), 'all');
            if IsNoLimitsEntered
                updateStatus(app, 'No limits were entered');
                ErrorCell(:, setdiff(1:9, app.ColumnIdx)) = true;
                [ErrorCellIdx(:, 1), ErrorCellIdx(:, 2)] = find(ErrorCell);
                addStyle(app.LayerParamUITable, ErrorCellColor, 'cell', ErrorCellIdx);
                start(ErrorCellTimer);
                return;
            end

            for l = 1 : height(app.UsedLayers)
                for c = app.ColumnIdx
                    if xor(isempty(app.UsedLayers{l, c-1}), isempty(app.UsedLayers{l, c+1}))                
                        ErrorCell(l, [c-1, c+1]) = true;
                    end
                    if all(~cellfun(@isempty, app.UsedLayers(l, [c-1, c+1]))) && ...
                            app.UsedLayers{l, c-1} > app.UsedLayers{l, c+1}
                        ErrorCell(l, [c-1, c+1]) = true;
                    end
                end
            end
            if any(ErrorCell, 'all')
                [ErrorCellIdx(:, 1), ErrorCellIdx(:, 2)] = find(ErrorCell);
                addStyle(app.LayerParamUITable, ErrorCellColor, 'cell', ErrorCellIdx);
                start(ErrorCellTimer);
                return;
            end

            simulateData(app, 'Auto');


        end

        % Value changed function: LambdaEditField
        function LambdaEditFieldValueChanged(app, event)
            app.Lambda = app.LambdaEditField.Value * 1e-9;
        end

        % Selection changed function: LayerParamUITable
        function LayerParamUITableSelectionChanged(app, event)
            app.SelectedCell = app.LayerParamUITable.Selection;
            app.LayerSelectionDropDown.Value = app.LayerSelectionDropDown.Items(app.SelectedCell(1));
           
            LayerSelectionDropDownValueChanged(app);
           
            if strcmp(app.FittingMode, 'Manual') && ~any(app.SelectedCell(2) == app.ColumnIdx)
                ShiftedIdx = deflectCellSelection;
                app.LayerParamUITable.Selection(2) = ShiftedIdx;
            end

            app.LayerSelectionDropDown.FontWeight = 'bold';
            ObjectHighlighted = app.LayerSelectionDropDown; 
            if any(app.SelectedCell(2) == app.ColumnIdx)
                SliderNames = app.ParamLetters + "Slider";
                ParamIdx = find(app.SelectedCell(2) == app.ColumnIdx);
                app.(SliderNames(ParamIdx)).FontWeight = 'bold';
                ObjectHighlighted(end + 1) = app.(SliderNames(ParamIdx)); 
            end

            HighlightSliderTimer = timer(StartDelay = 1, TimerFcn = @(~, ~) set(ObjectHighlighted, ...
                FontWeight = 'normal'));
            start(HighlightSliderTimer);

            function ShiftedIdx = deflectCellSelection
                if any(app.SelectedCell(2) == [1, 4, 7])
                    IdxShift = +1;
                elseif any(app.SelectedCell(2) == [3, 6, 9])
                    IdxShift = -1;
                else
                    IdxShift = 0;
                end
                ShiftedIdx = app.SelectedCell(2) + IdxShift;
            end
        end

        % Button pushed function: ResetApp
        function ResetAppPushed(app, event)
            cla(app.MainUIAxes);
            hold(app.MainUIAxes, 'off');

            % Clear stale handles
            app.SimHndl = [];
            app.ScatterDataHndl = [];

            % Clear simulated results
            app.SimulatedR = [];
            app.SimulatedTheta = [];

            % Clear table and layers
            app.Layers = cell(10, 9);
            app.LayerParamUITable.Data = cell(app.NumOfLayersSpinner.Value, 9);

            % Clear info boxes
            app.InfoTextArea.Value = '';
            app.nkdvalueTextArea.Value = {''};

            % Keep loaded data intact, only re-plot it
            if app.IsDataLoaded
                app.ScatterDataHndl = plotExperimentalData(app);
            end

            startupFcn(app);
        end

        % Value changed function: AutofitSlider
        function AutofitSliderValueChanged(app, event)
            value = app.AutofitSlider.Value;
        end

        % Display data changed function: LayerParamUITable
        function LayerParamUITableDisplayDataChanged(app, event)
      
            
        end

        % Value changed function: InfoTextArea
        function InfoTextAreaValueChanged(app, event)
            value = app.InfoTextArea.Value;
            
        end

        % Value changed function: nkdvalueTextArea
        function nkdvalueTextAreaValueChanged(app, event)
            value = app.nkdvalueTextArea.Value;
            
        end

        % Callback function: ResetTool
        function ResetToolClicked(app, event)
          
            % Clear axes
            cla(app.MainUIAxes);
            hold(app.MainUIAxes, 'off');

            % Clear graphic handles
            app.SimHndl = [];
            app.ScatterDataHndl = [];

            % Clear table and layers
            app.Layers = cell(10, 9);
            app.LayerParamUITable.Data = cell(app.NumOfLayersSpinner.Value, 9);

            % Clear info boxes
            app.InfoTextArea.Value = '';
            app.nkdvalueTextArea.Value = {''};

            % Clear data
            app.ExperimentalData = [];
            app.IsDataLoaded = false;
            app.LoadDataTextArea.Value = '';
            app.LoadDataButton.Text = 'Load data';
            app.AutoButton.Enable = 'off';

            % Restart app
            startupFcn(app);
        end

        % Callback function: ExportTool
        function ExportToolClicked(app, event)
            % Check if there is anything to export
            if ~app.IsDataLoaded
                updateStatus(app, '(!) No data to export. Please load data and run simulation first.');
                return;
            end

            % Open save dialog with date prefix
            DatePrefix = datestr(now, 'yyyymmdd_HHMMSS');
            DefaultName = sprintf('%s_SPR_Results.xlsx', DatePrefix);
            [FileName, FilePath] = uiputfile('*.xlsx', 'Save results as Excel file', DefaultName);
            figure(app.UIFigure);

            % User cancelled
            if isequal(FileName, 0)
                return;
            end

            FullPath = fullfile(FilePath, FileName);

            % Section 1 - Best fit parameters
            NumLayers = app.NumOfLayersSpinner.Value;
            n_vals = zeros(NumLayers, 1);
            k_vals = zeros(NumLayers, 1);
            d_vals = zeros(NumLayers, 1);
            for li = 1 : NumLayers
                if ~isempty(app.LayerParamUITable.Data{li,2}), n_vals(li) = app.LayerParamUITable.Data{li,2}; end
                if ~isempty(app.LayerParamUITable.Data{li,5}), k_vals(li) = app.LayerParamUITable.Data{li,5}; end
                if ~isempty(app.LayerParamUITable.Data{li,8}), d_vals(li) = app.LayerParamUITable.Data{li,8}; end
            end

            % Section 1 - Experimental and simulated data (columns A-C)
            DataHeaderRow = 1;
            writecell({'Angle (deg)', 'Reflectance (Exp)', 'Reflectance (Sim)'}, FullPath, Sheet = 'Results', Range = sprintf('A%d', DataHeaderRow));

             for i = 1 : length(app.ExperimentalData(:,1))
                 ExpAngle = app.RawThetaRange(i); % Original rotation stage angle for export
                 ExpRef   = app.ExperimentalData(i, 2);
                 % Use converted internal angle for simulation interpolation
                 ConvertedAngle = app.ExperimentalData(i, 1);
                 if ~isempty(app.SimHndl) && isvalid(app.SimHndl)
                     SimRef = interp1(app.SimHndl.XData, app.SimHndl.YData, ConvertedAngle, 'linear', 'extrap');
                 else
                     SimRef = NaN;
                 end
                writecell({ExpAngle, ExpRef, SimRef}, FullPath, Sheet = 'Results', Range = sprintf('A%d', DataHeaderRow + i));
            end

            % Section 2 - Best fit parameters (columns F onwards)
            % Write resonance info
            writecell({'Parameter', 'Value'}, FullPath, Sheet = 'Results', Range = 'F1');
            writecell({'Resonance Angle (°)', app.InfoTextArea.Value}, FullPath, Sheet = 'Results', Range = 'F2');
            writecell({'Layer', 'n', 'k', 'd (nm)'}, FullPath, Sheet = 'Results', Range = 'F4');
            for li = 1 : NumLayers
                writecell({li, n_vals(li), k_vals(li), d_vals(li)}, FullPath, Sheet = 'Results', Range = sprintf('F%d', li+1));
            end

            updateStatus(app, sprintf('Results exported to %s', FileName));
        end

        % Callback function: CopyGraph
        function CopyGraphClicked(app, event)
            % Create a temporary invisible figure
            TempFig = figure('Visible', 'off', 'Color', 'white');
            TempFig.Position = [100, 100, 800, 600];

            % Create new axes in temp figure
            TempAxes = axes(TempFig);

            % Copy all children (plots) from MainUIAxes to TempAxes
            TempChildren = copyobj(app.MainUIAxes.Children, TempAxes);

            % Copy axis labels and limits
            TempAxes.XLim   = app.MainUIAxes.XLim;
            TempAxes.YLim   = app.MainUIAxes.YLim;
            TempAxes.XLabel.String = app.MainUIAxes.XLabel.String;
            TempAxes.YLabel.String = app.MainUIAxes.YLabel.String;
            TempAxes.Title.String  = app.MainUIAxes.Title.String;
            TempAxes.Box    = app.MainUIAxes.Box;
            TempAxes.XGrid  = app.MainUIAxes.XGrid;
            TempAxes.YGrid  = app.MainUIAxes.YGrid;

            % Copy to clipboard
            print(TempFig, '-clipboard', '-dbitmap');

            % Clean up
            close(TempFig);

            updateStatus(app, 'Graph copied to clipboard. You can now paste it anywhere.');
        end

        % Value changed function: PrismAngleEditField
        function PrismAngleEditFieldValueChanged(app, event)
           app.PrismDegree = app.PrismAngleEditField.Value;
            if app.IsDataLoaded
                updateStatus(app, '(!) Prism angle changed — please reload data to reapply angle conversion.');
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {742, 742};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {488, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'PutraSPR';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'AppLogo.png');
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.ResetTool = uipushtool(app.Toolbar);
            app.ResetTool.Tooltip = {'Reset Software'};
            app.ResetTool.ClickedCallback = createCallbackFcn(app, @ResetToolClicked, true);
            app.ResetTool.Icon = fullfile(pathToMLAPP, 'reset.png');
            app.ExportTool = uipushtool(app.Toolbar);
            app.ExportTool.Tooltip = {'Save Data'};
            app.ExportTool.ClickedCallback = createCallbackFcn(app, @ExportToolClicked, true);
            app.ExportTool.Icon = fullfile(pathToMLAPP, 'export.png');
            app.CopyGraph = uitoggletool(app.Toolbar);
            app.CopyGraph.Tooltip = {'Copy the graph'};
            app.CopyGraph.ClickedCallback = createCallbackFcn(app, @CopyGraphClicked, true);
            app.CopyGraph.Icon = fullfile(pathToMLAPP, '33296.png');
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.SetupPanel = uipanel(app.GridLayout2);
            app.SetupPanel.Title = 'Setup';
            app.NooflayersSpinnerLabel = uilabel(app.SetupPanel);
            app.NooflayersSpinnerLabel.Text = 'No. of layers';
            app.NumOfLayersSpinner = uispinner(app.SetupPanel);
            app.NumOfLayersSpinner.Limits = [1 10];
            app.NumOfLayersSpinner.ValueChangedFcn = createCallbackFcn(app, @NumOfLayersSpinnerValueChanged, true);
            app.NumOfLayersSpinner.Value = 3;
            app.LoadDataButton = uibutton(app.SetupPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load data';
            app.LoadDataTextArea = uitextarea(app.SetupPanel);
            app.LoadDataTextArea.Editable = 'off';
            app.nmLabel = uilabel(app.SetupPanel);
            app.nmLabel.Text = '𝝀 (nm)';
            app.LambdaEditField = uieditfield(app.SetupPanel, 'numeric');
            app.LambdaEditField.ValueChangedFcn = createCallbackFcn(app, @LambdaEditFieldValueChanged, true);
            app.PrismtypeDropDownLabel = uilabel(app.SetupPanel);
            app.PrismtypeDropDownLabel.Text = 'Prism type';
            app.PrismTypeDropDown = uidropdown(app.SetupPanel);
            app.PrismTypeDropDown.Items = {'Reflection', 'Non-reflection'};
            app.PrismTypeDropDown.Value = 'Reflection';
            app.ResetApp = uibutton(app.SetupPanel, 'push');
            app.ResetApp.ButtonPushedFcn = createCallbackFcn(app, @ResetAppPushed, true);
            app.ResetApp.Text = 'Reset';
            app.PrismAngleEditFieldLabel = uilabel(app.SetupPanel);
            app.PrismAngleEditFieldLabel.Text = 'Prism Angle (°)';
            app.PrismAngleEditField = uieditfield(app.SetupPanel, 'numeric');
            app.PrismAngleEditField.ValueChangedFcn = createCallbackFcn(app, @PrismAngleEditFieldValueChanged, true);
            app.StatusTextArea = uitextarea(app.GridLayout2);
            app.StatusTextArea.Editable = 'off';
            app.SimulationPanel = uipanel(app.GridLayout2);
            app.SimulationPanel.Title = 'Simulation';
            app.LayerParamUITable = uitable(app.SimulationPanel);
            app.LayerParamUITable.ColumnName = {''};
            app.LayerParamUITable.RowName = {};
            app.LayerParamUITable.ColumnEditable = true;
            app.LayerParamUITable.CellEditCallback = createCallbackFcn(app, @LayerParamUITableCellEdit, true);
            app.LayerParamUITable.DisplayDataChangedFcn = createCallbackFcn(app, @LayerParamUITableDisplayDataChanged, true);
            app.LayerParamUITable.SelectionChangedFcn = createCallbackFcn(app, @LayerParamUITableSelectionChanged, true);
            app.LayerParamUITable.Multiselect = 'off';
            app.nLeftButton = uibutton(app.SimulationPanel, 'push');
            app.nLeftButton.ButtonPushedFcn = createCallbackFcn(app, @TuningButtonPushed, true);
            app.nLeftButton.Tag = 'nl';
            app.nLeftButton.Text = '◀';
            app.nRightButton = uibutton(app.SimulationPanel, 'push');
            app.nRightButton.ButtonPushedFcn = createCallbackFcn(app, @TuningButtonPushed, true);
            app.nRightButton.Tag = 'nr';
            app.nRightButton.Text = '▶';
            app.AutofitLabel = uilabel(app.SimulationPanel);
            app.AutofitLabel.Text = 'Auto-fitting';
            app.AutofitSlider = uislider(app.SimulationPanel);
            app.AutofitSlider.Limits = [5 500];
            app.AutofitSlider.MajorTicks = [5 104 203 302 401 500];
            app.AutofitSlider.ValueChangedFcn = createCallbackFcn(app, @AutofitSliderValueChanged, true);
            app.AutofitSlider.ValueChangingFcn = createCallbackFcn(app, @AutofitSliderValueChanging, true);
            app.AutofitSlider.MinorTicks = [5 16 27 38 49 60 71 82 93 104 115 126 137 148 159 170 181 192 203 214 225 236 247 258 269 280 291 302 313 324 335 346 357 368 379 390 401 412 423 434 445 456 467 478 489 500];
            app.AutofitSlider.Tooltip = {'Coarser tuning is less precise, but faster, and vice versa.'};
            app.AutofitSlider.Value = 50;
            app.ButtonTuningLabel = uilabel(app.SimulationPanel);
            app.ButtonTuningLabel.Text = 'Button tuning';
            app.ButtonTuningSlider = uislider(app.SimulationPanel);
            app.ButtonTuningSlider.Limits = [1 5];
            app.ButtonTuningSlider.MajorTicks = [1 2 3 4 5];
            app.ButtonTuningSlider.ValueChangedFcn = createCallbackFcn(app, @ButtonTuningSliderValueChanged, true);
            app.ButtonTuningSlider.MinorTicks = [1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3 3.1 3.2 3.3 3.4 3.5 3.6 3.7 3.8 3.9 4 4.1 4.2 4.3 4.4 4.5 4.6 4.7 4.8 4.9 5];
            app.ButtonTuningSlider.Value = 3;
            app.LayerSelectionDropDown = uidropdown(app.SimulationPanel);
            app.LayerSelectionDropDown.Items = {'Layer 1', 'Layer 2'};
            app.LayerSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @LayerSelectionDropDownValueChanged, true);
            app.LayerSelectionDropDown.Value = 'Layer 1';
            app.SliderLabel = uilabel(app.SimulationPanel);
            app.SliderLabel.Text = '𝑛';
            app.nSlider = uislider(app.SimulationPanel);
            app.nSlider.ValueChangedFcn = createCallbackFcn(app, @nSliderValueChanged, true);
            app.nSlider.ValueChangingFcn = createCallbackFcn(app, @nSliderValueChanging, true);
            app.kLeftButton = uibutton(app.SimulationPanel, 'push');
            app.kLeftButton.ButtonPushedFcn = createCallbackFcn(app, @TuningButtonPushed, true);
            app.kLeftButton.Tag = 'kl';
            app.kLeftButton.Text = '◀';
            app.kRightButton = uibutton(app.SimulationPanel, 'push');
            app.kRightButton.ButtonPushedFcn = createCallbackFcn(app, @TuningButtonPushed, true);
            app.kRightButton.Tag = 'kr';
            app.kRightButton.Text = '▶';
            app.kLabel = uilabel(app.SimulationPanel);
            app.kLabel.Text = 'k';
            app.kSlider = uislider(app.SimulationPanel);
            app.kSlider.ValueChangedFcn = createCallbackFcn(app, @kSliderValueChanged, true);
            app.kSlider.ValueChangingFcn = createCallbackFcn(app, @kSliderValueChanging, true);
            app.dLeftButton = uibutton(app.SimulationPanel, 'push');
            app.dLeftButton.ButtonPushedFcn = createCallbackFcn(app, @TuningButtonPushed, true);
            app.dLeftButton.Tag = 'dl';
            app.dLeftButton.Text = '◀';
            app.dRightButton = uibutton(app.SimulationPanel, 'push');
            app.dRightButton.ButtonPushedFcn = createCallbackFcn(app, @TuningButtonPushed, true);
            app.dRightButton.Tag = 'dr';
            app.dRightButton.Text = '▶';
            app.nmSliderLabel = uilabel(app.SimulationPanel);
            app.nmSliderLabel.Text = '𝑑 (nm)';
            app.dSlider = uislider(app.SimulationPanel);
            app.dSlider.ValueChangedFcn = createCallbackFcn(app, @dSliderValueChanged, true);
            app.dSlider.ValueChangingFcn = createCallbackFcn(app, @dSliderValueChanging, true);
            app.AutofitRunButton = uibutton(app.SimulationPanel, 'push');
            app.AutofitRunButton.ButtonPushedFcn = createCallbackFcn(app, @AutofitRunButtonPushed, true);
            app.AutofitRunButton.Text = 'Run';
            app.AutofitValueLabel = uilabel(app.SimulationPanel);
            app.AutofitValueLabel.Text = '';
            app.ResetRangesButton = uibutton(app.SimulationPanel, 'push');
            app.ResetRangesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetRangesButtonPushed, true);
            app.ResetRangesButton.Text = 'Reset ranges';
            app.FittingModeButtonGroup = uibuttongroup(app.SimulationPanel);
            app.FittingModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FittingModeButtonGroupSelectionChanged, true);
            app.OffButton = uiradiobutton(app.FittingModeButtonGroup);
            app.OffButton.Text = 'Off';
            app.ManualButton = uiradiobutton(app.FittingModeButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.AutoButton = uiradiobutton(app.FittingModeButtonGroup);
            app.AutoButton.Tooltip = {'Enter lower and upper limit for acquired parameters'};
            app.AutoButton.Text = 'Auto';
            app.FittingModeLabel = uilabel(app.SimulationPanel);
            app.FittingModeLabel.Text = 'Fitting mode';
            app.CoarserLabel = uilabel(app.SimulationPanel);
            app.CoarserLabel.Text = 'Coarser';
            app.FinerLabel = uilabel(app.SimulationPanel);
            app.FinerLabel.Text = 'Finer';
            app.CoarserLabel_2 = uilabel(app.SimulationPanel);
            app.CoarserLabel_2.Text = 'Coarser';
            app.FinerLabel_3 = uilabel(app.SimulationPanel);
            app.FinerLabel_3.Text = 'Finer';
            app.Fornkandd1LowerLimit2UpperLimitLabel = uilabel(app.SimulationPanel);
            app.Fornkandd1LowerLimit2UpperLimitLabel.Text = {'For n,k and d'; '1 = Lower Limit'; '2 = Upper Limit'};
            app.Panel = uipanel(app.GridLayout2);
            app.StatusLamp = uilamp(app.GridLayout2);
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanelGridLayout = uigridlayout(app.RightPanel);
            app.TabGroup = uitabgroup(app.RightPanelGridLayout);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.MainTabGridLayout = uigridlayout(app.MainTab);
            app.MainUIAxes = uiaxes(app.MainTabGridLayout);
            xlabel(app.MainUIAxes, 'Angle (°)')
            ylabel(app.MainUIAxes, 'Reflectance')
            colormap(app.MainUIAxes, 'prism')
            app.AppNameLabel = uilabel(app.MainTabGridLayout);
            app.AppNameLabel.Text = '';
            app.InfoPanel = uipanel(app.MainTabGridLayout);
            app.InfoPanel.Title = 'Info';
            app.InfoTextArea = uieditfield(app.InfoPanel, 'text');
            app.InfoTextArea.ValueChangedFcn = createCallbackFcn(app, @InfoTextAreaValueChanged, true);
            app.InfoTextArea.Editable = 'off';
            app.nkdvalueTextAreaLabel = uilabel(app.InfoPanel);
            app.nkdvalueTextAreaLabel.Text = 'n, k, d value';
            app.nkdvalueTextArea = uitextarea(app.InfoPanel);
            app.nkdvalueTextArea.ValueChangedFcn = createCallbackFcn(app, @nkdvalueTextAreaValueChanged, true);
            app.nkdvalueTextArea.Editable = 'off';
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.ResultsTable = uitable(app.DataTab);
            app.ResultsTable.ColumnName = {'Angle (deg)'; 'Reflectance (Exp)'; 'Reflectance (Sim)'};
            app.ResultsTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SPRSoftware

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end